classdef DeletionConfirm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ConfirmButton         matlab.ui.control.Button
        AreyousuretodeletetherecordLabel  matlab.ui.control.Label
        MessageTextAreaLabel  matlab.ui.control.Label
        MessageTextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        callingApp % callingApp of the instance
        tableName
        table
        query
        tableComponent
        selectedKey
        additionalTables
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, tableName)
            app.callingApp = mainapp;
            app.tableName = tableName;
            switch tableName
                case 'Subject'
                    app.query = app.callingApp.currentSubjectQuery;
                    app.table = subject.Subject;
                    app.selectedKey = mainapp.selectedSubjectKey;
                    app.tableComponent = mainapp.SubjectTable;
                    app.additionalTables = {subject.Nickname, subject.Zygosity, subject.Death};
                case 'Strain'
                    app.table = subject.Strain;
                    app.query = subject.Strain;
                    app.selectedKey = mainapp.selectedStrainKey;
                    app.tableComponent = mainapp.StrainTable;
                    app.additionalTables = {};
                case 'Source'
                    app.table = lab.Source;
                    app.query = lab.Source;
                    app.selectedKey = mainapp.selectedSourceKey;
                    app.tableComponent = mainapp.SourceTable;
                    app.additionalTables = {}; 
                case 'Allele'
                    app.table = subject.Allele;
                    app.query = subject.Allele;
                    app.selectedKey = mainapp.selectedAlleleKey;
                    app.tableComponent = mainapp.AlleleTable;
                    app.additionalTables = {}; 
                case 'StrainAllele'
                    app.table = subject.StrainAllele;
                    app.query = subject.StrainAllele;
                    app.selectedKey = mainapp.selectedStrainAlleleKey;
                    app.tableComponent = mainapp.StrainAlleleTable;
                    app.additionalTables = {}; 
            end
           
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
           
            dj.config('safemode', false)
            try
                del(app.table & app.selectedKey)
                app.MessageTextArea.Value = ...
                    sprintf('Successfully deleted entry %s!', ...
                            gui_utils.key2str(app.selectedKey));
            catch exception 
                app.MessageTextArea.Value = ...
                    sprintf('Error: %s', exception.message);
            end
            dj.config('safemode', true)
            
            app.ConfirmButton.Enable = 'off';
            
            if strcmp(extractAfter(class(app.table), '.'), 'StrainAllele')
                app.callingApp.loadTable(subject.StrainAllele, true)
            else
                app.callingApp.([app.tableName 'Table']).Data = struct2cell(fetch(app.query, '*'))';
            end
            
            % assign selection after deleting the data.
            data_size = size(app.tableComponent.Data);
            if app.callingApp.(['selected' app.tableName 'Index']) > data_size(1)
                for f = fields(app.callingApp.(['selected' app.tableName 'Key']))'
                    app.callingApp.(['selected' app.tableName 'Key']).(f{1}) = 'dummy';
                end

                app.callingApp.(['selected' app.tableName 'Index']) = [];
            else
                for f = fields(app.callingApp.(['selected' app.tableName 'Key']))'
                    column_idx = strcmp(app.tableComponent.ColumnName, f{1});
                    fieldValue = app.tableComponent.Data(...
                        app.callingApp.(['selected' app.tableName 'Index']), column_idx);
                    fieldValue = fieldValue{1};
                    app.callingApp.(['selected' app.tableName 'Key']).(f{1}) = fieldValue;
                end
                
            end
            for t = app.additionalTables'
                app.callingApp.loadTable(t{1}, true)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.(['Delete' app.tableName 'Button']).Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.AreyousuretodeletetherecordLabel = uilabel(app.UIFigure);
            app.AreyousuretodeletetherecordLabel.Text = 'Are you sure to delete the record?';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeletionConfirm(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end