classdef EditAlleleDialogue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AlleleRecordTable     matlab.ui.control.Table
        InsertrecordButton    matlab.ui.control.Button
        MessageTextAreaLabel  matlab.ui.control.Label
        MessageTextArea       matlab.ui.control.TextArea
        TitleTextArea         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp  % main app object
        query
        mode
        conn
        selectedAlleleKey
        selectedAlleleIndex
        selected = false
        currentAlleleTable
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mode)
            
            % Store main app in property for CloseRequestFcn to use
             if nargin < 3
                app.mode = 'add';
            end
            app.mode = mode;
            app.CallingApp = mainapp;
            app.conn = mainapp.conn;
            app.selectedAlleleKey = mainapp.selectedAlleleKey;
            app.selectedAlleleIndex = mainapp.selectedAlleleIndex;            
            if ~isempty(fetch(subject.Allele & app.selectedAlleleKey))
                app.selected = true;
            end            
            app.query = subject.Allele;
            app.AlleleRecordTable.ColumnName = app.query.header.names;
            app.AlleleRecordTable.ColumnEditable = true(size(app.query.header.names));
           
            switch app.mode
                case 'add'
                    app.TitleTextArea.Value = {'New Allele'};
                    app.AlleleRecordTable.Data = {'', '', '', '', ''};
                case 'update'
                    app.TitleTextArea.Value = {'Update Allele'};
                    currentData = fetch(subject.Allele, '*');
                    app.AlleleRecordTable.Data = struct2cell(currentData(app.selectedAlleleIndex(1)))';       
                    app.selectedAlleleKey.Editable = false;                    
            end           
        end

        % Cell edit callback: AlleleRecordTable
        function AlleleRecordTableCellEdit(app, event)
            app.currentAlleleTable.indices = event.Indices;
            app.currentAlleleTable.newData = event.NewData;  
        end

        % Button pushed function: InsertrecordButton
        function InsertrecordButtonPushed(app, event)
            switch app.mode
                case 'add'          
                    for i = 1:length(app.AlleleRecordTable.ColumnName)
                        name = app.AlleleRecordTable.ColumnName{i};
                        value = app.AlleleRecordTable.Data{i};
                        if ~isempty(value)
                            entry.(name) = value;
                        end
                    end
                    try
                        insert(subject.Allele, entry)
                        app.MessageTextArea.Value = 'Success!';
                        app.AlleleRecordTable.Data = {'', '', '', '', ''};
                        app.CallingApp.loadTable(subject.Allele)
                    catch e
                        app.MessageTextArea.Value = ['The following error happens during insertion, please edit record: ', e.message];
                    end
                case 'update' 
                    dj.config('safemode', false)
                    oldAlleleEntry = fetch(subject.Allele & app.selectedAlleleKey, '*');
                    oldAlleleFields = fields(oldAlleleEntry);
                    newAlleleEntry = gui_utils.cleanupEntry(app.currentAlleleTable);
                    newAlleleFields = fields(newAlleleEntry);
                    ii=newAlleleEntry.indices(2);
                    f = oldAlleleFields{ii};g = oldAlleleFields{5}; h = newAlleleFields{2};
                    newValue=newAlleleEntry.(h);
                    try
                        update(subject.Allele & app.selectedAlleleKey,f,newValue)
                        timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                        update(subject.Allele & app.selectedAlleleKey, g, timeNowStr)                        
                        app.MessageTextArea.Value =['Successfully updated Allele!'];
                    catch exception                
                        app.MessageTextArea.Value{end+1} = ...
                        sprintf('Error when updating the field %s: %s', f, exception.message);
                end
                dj.config('safemode', true)
                app.CallingApp.loadTable(subject.Allele);
            end
                    
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            app.CallingApp.AddAlleleButton.Enable = 'on';
            if app.selected
                app.CallingApp.UpdateAlleleButton.Enable = 'on';
                app.CallingApp.DeleteAlleleButton.Enable = 'on';
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.AlleleRecordTable = uitable(app.UIFigure);
            app.AlleleRecordTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AlleleRecordTable.RowName = {};
            app.AlleleRecordTable.CellEditCallback = createCallbackFcn(app, @AlleleRecordTableCellEdit, true);
            app.InsertrecordButton = uibutton(app.UIFigure, 'push');
            app.InsertrecordButton.ButtonPushedFcn = createCallbackFcn(app, @InsertrecordButtonPushed, true);
            app.InsertrecordButton.Text = 'Insert record';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea.Value = {'New Strain'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditAlleleDialogue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end