classdef EditSourceDialogue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SourceRecordTable     matlab.ui.control.Table
        InsertrecordButton    matlab.ui.control.Button
        MessageTextAreaLabel  matlab.ui.control.Label
        MessageTextArea       matlab.ui.control.TextArea
        TitleTextArea         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp  % main app object
        query
        mode
        conn
        selectedSourceKey
        selectedSourceIndex
        selected = false
        currentSourceTable
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mode)
            
            % Store main app in property for CloseRequestFcn to use
             if nargin < 3
                app.mode = 'add';
            end
            app.mode = mode;
            app.CallingApp = mainapp;
            app.conn = mainapp.conn;
            app.selectedSourceKey = mainapp.selectedSourceKey;
            app.selectedSourceIndex = mainapp.selectedSourceIndex;            
            if ~isempty(fetch(lab.Source & app.selectedSourceKey))
                app.selected = true;
            end            
            app.query = lab.Source;
            app.SourceRecordTable.ColumnName = app.query.header.names;
            app.SourceRecordTable.ColumnEditable = true(size(app.query.header.names));
           
            switch app.mode
                case 'add'
                    app.TitleTextArea.Value = {'New Source'};
                    app.SourceRecordTable.Data = {'', '', '', '', '',''};
                case 'update'
                    app.TitleTextArea.Value = {'Update Source'};
                    currentData = fetch(lab.Source, '*');
                    app.SourceRecordTable.Data = struct2cell(currentData(app.selectedSourceIndex(1)))';       
                    app.selectedSourceKey.Editable = false;                    
            end           
        end

        % Cell edit callback: SourceRecordTable
        function SourceRecordTableCellEdit(app, event)
            app.currentSourceTable.indices = event.Indices;
            app.currentSourceTable.newData = event.NewData;  
        end

        % Button pushed function: InsertrecordButton
        function InsertrecordButtonPushed(app, event)
            switch app.mode
                case 'add'          
                    for i = 1:length(app.SourceRecordTable.ColumnName)
                        name = app.SourceRecordTable.ColumnName{i};
                        value = app.SourceRecordTable.Data{i};
                        if ~isempty(value)
                            entry.(name) = value;
                        end
                    end
                    try
                        insert(lab.Source, entry)
                        app.MessageTextArea.Value = 'Success!';
                        app.SourceRecordTable.Data = {'', '', '', '', '', ''};
                        app.CallingApp.loadTable(lab.Source)
                    catch e
                        app.MessageTextArea.Value = ['The following error happens during insertion, please edit record: ', e.message];
                    end
                case 'update' 
                    dj.config('safemode', false)
                    oldSourceEntry = fetch(lab.Source & app.selectedSourceKey, '*');
                    oldSourceFields = fields(oldSourceEntry);
                    newSourceEntry = gui_utils.cleanupEntry(app.currentSourceTable);
                    newSourceFields = fields(newSourceEntry);
                    ii=newSourceEntry.indices(2);
                    f = oldSourceFields{ii};g = oldSourceFields{6}; h = newSourceFields{2};
                    newValue=newSourceEntry.(h);
                    try
                        update(lab.Source & app.selectedSourceKey,f,newValue)
                        timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                        update(lab.Source & app.selectedSourceKey, g, timeNowStr)                        
                        app.MessageTextArea.Value =['Successfully updated Source!'];
                    catch exception                
                        app.MessageTextArea.Value{end+1} = ...
                        sprintf('Error when updating the field %s: %s', f, exception.message);
                end
                dj.config('safemode', true)
                app.CallingApp.loadTable(lab.Source);
            end
                    
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            app.CallingApp.AddSourceButton.Enable = 'on';
            if app.selected
                app.CallingApp.UpdateSourceButton.Enable = 'on';
                app.CallingApp.DeleteSourceButton.Enable = 'on';
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.SourceRecordTable = uitable(app.UIFigure);
            app.SourceRecordTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SourceRecordTable.RowName = {};
            app.SourceRecordTable.CellEditCallback = createCallbackFcn(app, @SourceRecordTableCellEdit, true);
            app.InsertrecordButton = uibutton(app.UIFigure, 'push');
            app.InsertrecordButton.ButtonPushedFcn = createCallbackFcn(app, @InsertrecordButtonPushed, true);
            app.InsertrecordButton.Text = 'Insert record';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea.Value = {'New Strain'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditSourceDialogue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end