classdef EditStrainAlleleDialogue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StrainAlleleRecordTable  matlab.ui.control.Table
        InsertrecordButton       matlab.ui.control.Button
        MessageTextAreaLabel     matlab.ui.control.Label
        MessageTextArea          matlab.ui.control.TextArea
        TitleTextArea            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp  % main app object
        query
        mode
        conn
        selectedStrainAlleleKey
        selectedStrainAlleleIndex
        selected = false
        currentStrainAlleleTable
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mode)
            
            % Store main app in property for CloseRequestFcn to use
             if nargin < 3
                app.mode = 'add';
            end
            app.mode = mode;
            app.CallingApp = mainapp;
            app.conn = mainapp.conn;
            app.selectedStrainAlleleKey = mainapp.selectedStrainAlleleKey;
            app.selectedStrainAlleleIndex = mainapp.selectedStrainAlleleIndex;            
            if ~isempty(fetch(subject.StrainAllele & app.selectedStrainAlleleKey))
                app.selected = true;
            end            
            app.query = subject.StrainAllele;
            app.StrainAlleleRecordTable.ColumnName = app.query.header.names;
            app.StrainAlleleRecordTable.ColumnEditable = true(size(app.query.header.names));
           
            switch app.mode
                case 'add'
                    app.TitleTextArea.Value = {'New StrainAllele'};
                    app.StrainAlleleRecordTable.Data = {'', ''};
%                 case 'update'
%                     app.TitleTextArea.Value = {'Update StrainAllele'};
%                     currentData = fetch(subject.StrainAllele, '*');
%                     app.StrainAlleleRecordTable.Data = struct2cell(currentData(app.selectedStrainAlleleIndex(1)))';       
%                     app.selectedStrainAlleleKey.Editable = false;                    
            end           
        end

        % Cell edit callback: StrainAlleleRecordTable
        function StrainAlleleRecordTableCellEdit(app, event)
            app.currentStrainAlleleTable.indices = event.Indices;
            app.currentStrainAlleleTable.newData = event.NewData;  
        end

        % Button pushed function: InsertrecordButton
        function InsertrecordButtonPushed(app, event)
            switch app.mode
                case 'add'          
                    for i = 1:length(app.StrainAlleleRecordTable.ColumnName)
                        name = app.StrainAlleleRecordTable.ColumnName{i};
                        value = app.StrainAlleleRecordTable.Data{i};
                        if ~isempty(value)
                            entry.(name) = value;
                        end
                    end
                    try
                        insert(subject.StrainAllele, entry)
                        app.MessageTextArea.Value = 'Success!';
                        app.StrainAlleleRecordTable.Data = {'', ''};
                        app.CallingApp.loadTable(subject.StrainAllele)
                    catch e
                        app.MessageTextArea.Value = ['The following error happens during insertion, please edit record: ', e.message];
                    end
%                 case 'update' 
%                     dj.config('safemode', false)
%                     oldStrainAlleleEntry = fetch(subject.StrainAllele & app.selectedStrainAlleleKey, '*');
%                     oldStrainAlleleFields = fields(oldStrainAlleleEntry);
%                     newStrainAlleleEntry = gui_utils.cleanupEntry(app.currentStrainAlleleTable);
%                     newStrainAlleleFields = fields(newStrainAlleleEntry);
%                     ii=newStrainAlleleEntry.indices(2);
%                     f = oldStrainAlleleFields{ii}; h = newStrainAlleleFields{2};
%                     newValue=newStrainAlleleEntry.(h);
%                     try
%                         update(subject.StrainAllele & app.selectedStrainAlleleKey,f,newValue)
%                         app.MessageTextArea.Value =['Successfully updated StrainAllele!'];
%                     catch exception                
%                         app.MessageTextArea.Value{end+1} = ...
%                         sprintf('Error when updating the field %s: %s', f, exception.message);
%                 end
                dj.config('safemode', true)
                app.CallingApp.loadTable(subject.StrainAllele);
            end
                    
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            app.CallingApp.AddStrainAlleleButton.Enable = 'on';
            if app.selected
%                 app.CallingApp.UpdateStrainAlleleButton.Enable = 'on';
                app.CallingApp.DeleteStrainAlleleButton.Enable = 'on';
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.StrainAlleleRecordTable = uitable(app.UIFigure);
            app.StrainAlleleRecordTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StrainAlleleRecordTable.RowName = {};
            app.StrainAlleleRecordTable.CellEditCallback = createCallbackFcn(app, @StrainAlleleRecordTableCellEdit, true);
            app.InsertrecordButton = uibutton(app.UIFigure, 'push');
            app.InsertrecordButton.ButtonPushedFcn = createCallbackFcn(app, @InsertrecordButtonPushed, true);
            app.InsertrecordButton.Text = 'Insert record';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea.Value = {'New Strain'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditStrainAlleleDialogue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end