classdef EditStrainDialogue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        StrainRecordTable     matlab.ui.control.Table
        InsertrecordButton    matlab.ui.control.Button
        MessageTextAreaLabel  matlab.ui.control.Label
        MessageTextArea       matlab.ui.control.TextArea
        TitleTextArea         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp  % main app object
        query
        mode
        conn
        selectedStrainKey
        selectedStrainIndex
        selected = false
        currentStrainTable
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mode)
            
            % Store main app in property for CloseRequestFcn to use
             if nargin < 3
                app.mode = 'add';
            end
            app.mode = mode;
            app.CallingApp = mainapp;
            app.conn = mainapp.conn;
            app.selectedStrainKey = mainapp.selectedStrainKey;
            app.selectedStrainIndex = mainapp.selectedStrainIndex;            
            if ~isempty(fetch(subject.Strain & app.selectedStrainKey))
                app.selected = true;
            end            
            app.query = subject.Strain;
            app.StrainRecordTable.ColumnName = app.query.header.names;
            app.StrainRecordTable.ColumnEditable = true(size(app.query.header.names));
           
            switch app.mode
                case 'add'
                    app.TitleTextArea.Value = {'New Strain'};
                    app.StrainRecordTable.Data = {'', '', ''};
                case 'update'
                    app.TitleTextArea.Value = {'Update Strain'};
                    currentData = fetch(subject.Strain, '*');
                    app.StrainRecordTable.Data = struct2cell(currentData(app.selectedStrainIndex(1)))';       
                    app.selectedStrainKey.Editable = false;                    
            end           
        end

        % Cell edit callback: StrainRecordTable
        function StrainRecordTableCellEdit(app, event)
            app.currentStrainTable.indices = event.Indices;
            app.currentStrainTable.newData = event.NewData;  
        end

        % Button pushed function: InsertrecordButton
        function InsertrecordButtonPushed(app, event)
            switch app.mode
                case 'add'          
                    for i = 1:length(app.StrainRecordTable.ColumnName)
                        name = app.StrainRecordTable.ColumnName{i};
                        value = app.StrainRecordTable.Data{i};
                        if ~isempty(value)
                            entry.(name) = value;
                        end
                    end
                    try
                        insert(subject.Strain, entry)
                        app.MessageTextArea.Value = 'Success!';
                        app.StrainRecordTable.Data = {'', '', ''};
                        app.CallingApp.loadTable(subject.Strain)
                    catch e
                        app.MessageTextArea.Value = ['The following error happens during insertion, please edit record: ', e.message];
                    end
                case 'update' 
                    dj.config('safemode', false)
                    oldStrainEntry = fetch(subject.Strain & app.selectedStrainKey, '*');
                    oldStrainFields = fields(oldStrainEntry)
                    newStrainEntry = gui_utils.cleanupEntry(app.currentStrainTable);
                    newStrainFields = fields(newStrainEntry)
                    ii=newStrainEntry.indices(2);
                    f = oldStrainFields{ii};g = oldStrainFields{3}; h = newStrainFields{2};
                    newValue=newStrainEntry.(h);
                    try
                        update(subject.Strain & app.selectedStrainKey,f,newValue)
                        timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                        update(subject.Strain & app.selectedStrainKey, g, timeNowStr)
                        app.MessageTextArea.Value =['Successfully updated strain!'];
                    catch exception                
                        app.MessageTextArea.Value{end+1} = ...
                        sprintf('Error when updating the field %s: %s', f, exception.message);
                end
                dj.config('safemode', true)
                app.CallingApp.loadTable(subject.Strain);
            end
                    
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            app.CallingApp.AddStrainButton.Enable = 'on';
            if app.selected
                app.CallingApp.UpdateStrainButton.Enable = 'on';
                app.CallingApp.DeleteStrainButton.Enable = 'on';
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.StrainRecordTable = uitable(app.UIFigure);
            app.StrainRecordTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StrainRecordTable.RowName = {};
            app.StrainRecordTable.CellEditCallback = createCallbackFcn(app, @StrainRecordTableCellEdit, true);
            app.InsertrecordButton = uibutton(app.UIFigure, 'push');
            app.InsertrecordButton.ButtonPushedFcn = createCallbackFcn(app, @InsertrecordButtonPushed, true);
            app.InsertrecordButton.Text = 'Insert record';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea.Value = {'New Strain'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditStrainDialogue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end