classdef EditSubjectDialogue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainSubjectTableFormPanel      matlab.ui.container.Panel
        subject_idLabel_2              matlab.ui.control.Label
        subject_id                     matlab.ui.control.EditField
        dobLabel_2                     matlab.ui.control.Label
        dob                            matlab.ui.control.DatePicker
        subject_tsLabel_2              matlab.ui.control.Label
        subject_ts                     matlab.ui.control.EditField
        sexLabel_2                     matlab.ui.control.Label
        sex                            matlab.ui.control.DropDown
        mark_typeLabel_2               matlab.ui.control.Label
        mark_type                      matlab.ui.control.DropDown
        markEditFieldLabel_2           matlab.ui.control.Label
        mark                           matlab.ui.control.EditField
        strain_nameLabel_2             matlab.ui.control.Label
        strain_name                    matlab.ui.control.DropDown
        source_nameLabel_2             matlab.ui.control.Label
        source_name                    matlab.ui.control.DropDown
        breeding_pairEditFieldLabel_2  matlab.ui.control.Label
        breeding_pair                  matlab.ui.control.EditField
        subject_notesEditFieldLabel_2  matlab.ui.control.Label
        subject_notes                  matlab.ui.control.EditField
        AddamouseButton                matlab.ui.control.Button
        SubmiteditsButton              matlab.ui.control.Button
        ResetformsButton               matlab.ui.control.Button
        MessageTextAreaLabel           matlab.ui.control.Label
        MessageTextArea                matlab.ui.control.TextArea
        ZygosityTable                  matlab.ui.control.Table
        ZygosityLabel                  matlab.ui.control.Label
        DeathPanel                     matlab.ui.container.Panel
        dodLabel                       matlab.ui.control.Label
        dod                            matlab.ui.control.DatePicker
        reason_of_deathDropDownLabel   matlab.ui.control.Label
        reason_of_death                matlab.ui.control.DropDown
        death_tsEditFieldLabel         matlab.ui.control.Label
        death_ts                       matlab.ui.control.EditField
        death_notesEditFieldLabel      matlab.ui.control.Label
        death_notes                    matlab.ui.control.EditField
        NicknamePanel                  matlab.ui.container.Panel
        nicknameLabel                  matlab.ui.control.Label
        nickname                       matlab.ui.control.EditField
        OptionaltablesLabel            matlab.ui.control.Label
        TitleTextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        conn
        CallingApp
        currentSubjectForm % currentSubjectForm
        currentNicknameForm
        currentDeathForm
        currentZygosityData
        subjectColumnNames
        nicknameColumnNames
        deathColumnNames
        zygosityColumnNames
        formElements % mapping of column names and GUI elements
        selectedSubjectKey
        selected = false
        mode
    end
    
    methods (Access = private)
       
        function createFormElements(app, tableName)
           
            switch tableName 
                case 'subject'
                    table = subject.Subject;
                case 'nickname'
                    table = subject.Nickname;
                case 'death'
                    table = subject.Death; 
            end
            
            columnNames = app.([tableName 'ColumnNames']);
            
            for iColumn = 1:length(columnNames)
                column = columnNames{iColumn};
               
                if isprop(app, column)
                    if isempty(app.formElements)
                        app.formElements(1).name = column;
                        app.formElements(1).element = app.(column);
                        app.formElements(1).table = table;
                    else
                        elementNames = {app.formElements.name};
                        % skip any columns that already in the elements
                        if ~any(cellfun(@isequal, elementNames, repmat({column}, size(elementNames))))
                            app.formElements(end+1) = struct(...
                                'name', column, ...
                                'element', app.(column), ...
                                'table', table);
                        end
                    end
                end
            end
            
        end
        
        function initializeForms(app, empty)
            
            if nargin < 2
                empty = false;
            end
            
            if app.selected && ~empty
                subjectEntry = fetch(subject.Subject & app.selectedSubjectKey, '*');
                nicknameEntry = fetch(subject.Nickname & app.selectedSubjectKey, '*');
                deathEntry = fetch(subject.Death & app.selectedSubjectKey, '*');
            end
            
            for field = app.formElements                
                ele = field.element;
                tableName = extractAfter(field.table.className, '.');
                
                % get options if the current element is a dropdown list
                if isa(ele, 'matlab.ui.control.DropDown')
                    ele.Items = gui_utils.getOptions(field.table, field.name, app.conn);
                    ele.Items(end+1) = {''};
                    app.(['current' tableName 'Form']).(field.name) = '';
                    ele.Value = '';
                end
                
                if app.selected && ~empty
                    switch tableName
                        case 'Subject'
                            entry = subjectEntry;
                        case 'Nickname'
                            entry = nicknameEntry;
                        case 'Death'
                            entry = deathEntry;
                    end
                end
                
                if app.selected && ~empty && length(entry)
                    % load the forms with selected field
                    if isfield(entry, field.name)
                        fieldValue = entry.(field.name);
                    else
                        fieldValue = '';
                    end
                    if isa(ele, 'matlab.ui.control.DatePicker')
                        if isempty(fieldValue)
                            fieldValue = NaT;
                        else
                            fieldValue = datetime(fieldValue,'InputFormat','yyyy-MM-dd');
                        end
                    end
                    ele.Value = fieldValue;
                    app.(['current' tableName 'Form']).(field.name) = fieldValue;
                    
                    if strcmp(app.mode, 'add') && contains(field.name, '_ts')
                        timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                        ele.Value = timeNowStr;
                        app.(['current' tableName 'Form']).(field.name) = timeNowStr;
                    end
                       
                else
                    % get default values and insert data into the corresponding
                    % GUI elements
                    default = gui_utils.getDefaultValue(field.table, field.name);
                    if ~isempty(default)
                        ele.Value = default;
                        app.(['current' tableName 'Form']).(field.name) = default;
                    elseif contains(field.name, '_ts')
                        timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                        ele.Value = timeNowStr;
                        app.(['current' tableName 'Form']).(field.name) = timeNowStr;
                    else
                        if isa(ele, 'matlab.ui.control.DatePicker')
                            ele.Value = NaT;
                        else
                            ele.Value = '';
                        end
                        
                    end
                end
                
                
            end
        end
        
        function initializeZygosityTable(app, empty)
            
           if nargin < 2
               empty = false;
           end
            
           if app.selected && ~empty
               zygosityEntries = fetch(subject.Zygosity & app.selectedSubjectKey, '*');
               zygosityEntries = rmfield(zygosityEntries, 'subject_id');
           end
          
           columnNames = app.zygosityColumnNames(~strcmp(app.zygosityColumnNames, 'subject_id'));
           
           % five empty rows
           nRows = 5;
           app.ZygosityTable.Data = repmat({''}, nRows, length(columnNames));
           
           % if selected and entries not empty, load the first few rows
           % with existing entries
           if app.selected && ~empty && length(zygosityEntries)
                app.ZygosityTable.Data(1:length(zygosityEntries), :) = struct2cell(zygosityEntries)';
                
           end
           
           app.ZygosityTable.ColumnFormat = repmat({[]}, 1, length(columnNames));
           
           
           for iColumn = 1:length(columnNames)
               
                column = columnNames{iColumn};
                
                % if there is strain_name filled, get corresponding
                % alleles, otherwise give all the options in table
                % subject.Allele
                if ~isempty(app.strain_name.Value) && strcmp(column, 'allele_name')
                    
                    q = subject.StrainAllele & struct('strain_name', app.strain_name.Value);
                    if length(fetch(q))
                        options = fetchn(...
                            subject.StrainAllele & struct('strain_name', app.strain_name.Value), 'allele_name')';
                    else
                        options = {};
                    end
                else
                    options = gui_utils.getOptions(subject.Zygosity, column, app.conn);
                end
                
                if ~isempty(options)
                    app.ZygosityTable.ColumnFormat{iColumn} = options;
                end
                if contains(column, '_ts')
                    timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                    if strcmp(app.mode, 'add')
                        app.ZygosityTable.Data(:, iColumn) = repmat({timeNowStr}, nRows, 1);
                    elseif nRows > length(zygosityEntries)
                        app.ZygosityTable.Data(length(zygosityEntries) + 1:end, iColumn) = ...
                            repmat({timeNowStr}, nRows - length(zygosityEntries), 1);
                    end
                end
                
           end
           
        end
        
        function zygosityEntries = createZygosityEntries(app)
            zygosityEntries = struct();
            
            for iData = app.ZygosityTable.Data'
                
                if ~isempty(iData{1})
                    newEntry = struct('subject_id', app.currentSubjectForm.subject_id);
                    for iField = 1:length(iData)
                        newEntry.(app.zygosityColumnNames{iField+1}) = iData{iField};
                    end
                    newEntry = gui_utils.cleanupEntry(newEntry, subject.Zygosity);
                    
                    % if missing required field 'zygosity', complain
                    if ~any(strcmp(fields(newEntry), 'zygosity'))
                        app.MessageTextArea.Value{end+1} = sprinf( ...
                            'Field zygosity missing from record %s', iData{1});
                        continue
                    end
                    if isempty(fields(zygosityEntries))
                        zygosityEntries = newEntry;
                    else
                        zygosityEntries(end+1) = newEntry;
                    end
                end
            end
        end
        
        function insertRecords(app)
            
            subj_entry = gui_utils.cleanupEntry(app.currentSubjectForm);
            
            try
                insert(subject.Subject, subj_entry)
                app.MessageTextArea.Value = 'Successfully inserted Subject record!';
            catch exception                
                app.MessageTextArea.Value = sprintf('Error when inserting the Subject entry: %s', exception.message);
            end
            
            % if nickname form is not empty
            if ~isempty(app.currentNicknameForm) && ~isempty(app.currentNicknameForm.nickname)
                nicknameEntry = gui_utils.cleanupEntry(struct('subject_id', app.currentSubjectForm.subject_id, ...
                    'nickname', app.currentNicknameForm.nickname));
                
                 try
                     insert(subject.Nickname, nicknameEntry)
                     app.MessageTextArea.Value{end+1} = 'Successfully inserted Nickname record!';
                 catch exception
                     app.MessageTextArea.Value{end+1} = ...
                         sprintf('Error when inserting the Nickname entry: %s', exception.message);
                 end
            end
            
            % if death form is not empty
            if ~isempty(app.currentDeathForm) && isfield(app.currentDeathForm, 'dod') && ~isempty(app.currentDeathForm.dod) ...
                    && ~isnat(app.currentDeathForm.dod)
                deathEntry = app.currentDeathForm;
                deathEntry.subject_id = app.currentSubjectForm.subject_id;
                deathEntry = gui_utils.cleanupEntry(deathEntry);
                try
                    insert(subject.Death, deathEntry)
                    app.MessageTextArea.Value
                    app.MessageTextArea.Value{end+1} = 'Successfully inserted Death record!';
                catch exception
                    app.MessageTextArea.Value
                    app.MessageTextArea.Value{end+1} = ...
                        sprintf('Error when inserting the Death entry: %s', exception.message);
                end
            end
            
            % insert Zygosity
            zygosityEntries = app.createZygosityEntries();
            
            if ~isempty(fields(zygosityEntries))
                try
                    insert(subject.Zygosity, zygosityEntries)
                    app.MessageTextArea.Value{end+1} = 'Successfully inserted Zygosity records!';
                catch exception
                    app.MessageTextArea.Value{end+1} = ...
                        sprintf('Error when inserting the Zygostiy entries: %s', exception.message);
                end
            end
        end 
        
        function updateRecords(app)
            
            dj.config('safemode', false)
            oldSubjectEntry = fetch(subject.Subject & app.selectedSubjectKey, '*');
            newSubjectEntry = gui_utils.cleanupEntry(app.currentSubjectForm);
            newSubjectFields = fields(newSubjectEntry);
            
            % update the main table
            for field = fields(oldSubjectEntry)'
                f = field{1};
                if ~contains([newSubjectFields{:}], f) && ~strcmp(f, 'species')
                    % if a field is missing in an entry, restore it to the
                    % default value
                    newValue = gui_utils.getDefaultValue(subject.Subject, f);
                    if isequal(oldSubjectEntry.(f), newValue)
                        continue
                    end
                elseif strcmp(f, 'species') || contains(f, '_ts')
                    continue
                elseif ~isequal(oldSubjectEntry.(f), newSubjectEntry.(f))
                    newValue = newSubjectEntry.(f);
                    if strcmp(f, 'strain_name')
                        % if strain is changed, automatically delete the
                        % zygosity entries
                        try
                            del(subject.Zygosity & app.selectedSubjectKey)
                            app.MessageTextArea.Value{end+1} = ...
                                sprintf('Detected change in strain_name, zygosity entries deleted.');
                            
                            
                        catch exception
                            app.MessageTextArea.Value{end+1} = ...
                                sprintf('Detected change in strain_name, but failed to delete zygosity entries: %s.', ...
                                        exception.message);
                        end
                        
                    end
                else
                    continue
                end
                
                try
                    update(subject.Subject & app.selectedSubjectKey, f, newValue)
                    % TODO: show the old value and new value in the message
                    % as well, but have to deal with different data types
                    app.MessageTextArea.Value{end+1} = ...
                       sprintf('Successfully updated field "%s"!', f);
                    
                    timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                    update(subject.Subject & app.selectedSubjectKey, 'subject_ts', timeNowStr)
                    app.subject_ts.Value = timeNowStr;
                    app.currentSubjectForm.subject_ts = app.subject_ts.Value;
                catch exception                
                    app.MessageTextArea.Value{end+1} = ...
                        sprintf('Error when updating the field %s: %s', f, exception.message);
                end
                
            end
            
            % update nickname table
            if isempty(app.currentNicknameForm) || isempty(app.currentNicknameForm.nickname)
                app.MessageTextArea.Value = ...
                    gui_utils.updateTable(subject.Nickname, app.selectedSubjectKey, {},...
                                          app.MessageTextArea.Value);
            else
                nicknameEntry = struct('nickname', app.currentNicknameForm.nickname, ...
                                       'subject_id', app.selectedSubjectKey.subject_id);
                app.MessageTextArea.Value = ...
                    gui_utils.updateTable(subject.Nickname, app.selectedSubjectKey, nicknameEntry, ...
                                          app.MessageTextArea.Value);
            end
            
            % update Death table
            if isempty(app.currentDeathForm) || isempty(app.currentDeathForm.dod)
                app.MessageTextArea.Value = ...
                    gui_utils.updateTable(subject.Death, app.selectedSubjectKey, {},...
                                          app.MessageTextArea.Value);
            else
                deathEntry = app.currentDeathForm;
                deathEntry.subject_id = app.selectedSubjectKey.subject_id;
                app.MessageTextArea.Value = ...
                    gui_utils.updateTable(subject.Death, app.selectedSubjectKey, deathEntry, ...
                                          app.MessageTextArea.Value);
            end
            
            % update zygosity entries
            zygosityEntries = app.createZygosityEntries;
            app.MessageTextArea.Value = gui_utils.updateTable(...
                subject.Zygosity, app.selectedSubjectKey, zygosityEntries, app.MessageTextArea.Value);
            
            app.initializeForms()
            
            dj.config('safemode', true)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mode)
             % Store main app in property for CloseRequestFcn to use
            if nargin < 3
                app.mode = 'add';
            end
            app.mode = mode;
            app.CallingApp = mainapp;
            app.conn = mainapp.conn;
            app.selectedSubjectKey = mainapp.selectedSubjectKey;
            
            if ~isempty(fetch(subject.Subject & app.selectedSubjectKey))
                app.selected = true;
            end
            
            app.subjectColumnNames = mainapp.subjectColumnNames;
            app.nicknameColumnNames = mainapp.nicknameColumnNames;
            app.zygosityColumnNames = mainapp.zygosityColumnNames;
            app.deathColumnNames = mainapp.deathColumnNames;
            for table = {'subject', 'nickname', 'death'}
                app.createFormElements(table{1})
            end
            
            app.initializeForms();
            app.initializeZygosityTable();
            
            switch app.mode
                case 'add'
                    app.TitleTextArea.Value = {'New Subject'};
                case 'update'
                    app.TitleTextArea.Value = {'Update Subject'};
                    app.subject_id.Editable = false;
                    app.ResetformsButton.Enable = 'off';
            end
                
        end

        % Button pushed function: SubmiteditsButton
        function SubmiteditsButtonPushed(app, event)
            
            switch app.mode
                case 'add'
                    app.insertRecords()
                case 'update'
                    app.updateRecords()
            end
            app.CallingApp.loadTable(app.CallingApp.currentSubjectQuery);
            if strcmp(app.mode, 'update')
                app.CallingApp.loadTable(subject.Nickname, true)
                app.CallingApp.loadTable(subject.Zygosity, true)
                app.CallingApp.loadTable(subject.Death, true)
            end
            % update the strain dropdown options in main app
            app.CallingApp.strainFilterDropDown.Items = ...
                fetchn(subject.Strain & subject.Subject, 'strain_name');
        end

        % Value changed function: subject_id
        function subject_idValueChanged(app, event)
            value = app.subject_id.Value;
            app.currentSubjectForm.subject_id = value;
            app.subject_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            app.currentSubjectForm.subject_ts = app.subject_ts.Value;
        end

        % Callback function
        function subject_colony_nameValueChanged(app, event)
            
        end

        % Callback function
        function nicknameValueChanged(app, event)
            value = app.nickname.Value;
            app.currentSubjectForm.nickname = value;
            
        end

        % Value changed function: dob
        function dobValueChanged(app, event)
            value = app.dob.Value;
            app.currentSubjectForm.dob = value;
            app.subject_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            app.currentSubjectForm.subject_ts = app.subject_ts.Value;
        end

        % Value changed function: sex
        function sexValueChanged(app, event)
            value = app.sex.Value;
            app.currentSubjectForm.sex = value;
            app.subject_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            app.currentSubjectForm.subject_ts = app.subject_ts.Value;
        end

        % Value changed function: strain_name
        function strain_nameValueChanged(app, event)
            value = app.strain_name.Value;
            app.currentSubjectForm.strain_name = value;
            alleles = fetch(subject.StrainAllele & struct('strain_name', value));
            if isempty(alleles)
                app.ZygosityTable.ColumnFormat{1} = [];
            else
                app.ZygosityTable.ColumnFormat{1} = fetchn(subject.StrainAllele & alleles, 'allele_name')';
            end
            app.subject_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            app.currentSubjectForm.subject_ts = app.subject_ts.Value;
        end

        % Value changed function: mark_type
        function mark_typeValueChanged(app, event)
            value = app.mark_type.Value;
            app.currentSubjectForm.mark_type = value;
            app.subject_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            app.currentSubjectForm.subject_ts = app.subject_ts.Value;
        end

        % Value changed function: mark
        function markValueChanged(app, event)
            value = app.mark.Value;
            app.currentSubjectForm.mark = value;
            app.subject_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            app.currentSubjectForm.subject_ts = app.subject_ts.Value;
        end

        % Value changed function: source_name
        function source_nameValueChanged(app, event)
            value = app.source_name.Value;
            app.currentSubjectForm.source_name = value;
            app.subject_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            app.currentSubjectForm.subject_ts = app.subject_ts.Value;
        end

        % Value changed function: breeding_pair
        function breeding_pairValueChanged(app, event)
            value = app.breeding_pair.Value;
            app.currentSubjectForm.breeding_pair = value;
            app.subject_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            app.currentSubjectForm.subject_ts = app.subject_ts.Value;
        end

        % Value changed function: subject_ts
        function subject_tsValueChanged(app, event)
            value = app.subject_ts.Value;
            app.currentSubjectForm.subject_ts = value;
        end

        % Value changed function: subject_notes
        function subject_notesValueChanged(app, event)
            value = app.subject_notes.Value;
            app.currentSubjectForm.subject_notes = value;
            app.subject_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            app.currentSubjectForm.subject_ts = app.subject_ts.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.AddSubjectButton.Enable = 'on';
            if app.selected
                app.CallingApp.UpdateSubjectButton.Enable = 'on';
                app.CallingApp.DeleteSubjectButton.Enable = 'on';
            end
            delete(app)      
        end

        % Value changed function: nickname
        function nicknameValueChanged2(app, event)
            value = app.nickname.Value;
            app.currentNicknameForm.nickname = value;
        end

        % Value changed function: dod
        function dodValueChanged(app, event)
            value = app.dod.Value;
            app.currentDeathForm.dod = value;
            app.death_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
        end

        % Value changed function: reason_of_death
        function reason_of_deathValueChanged(app, event)
            value = app.reason_of_death.Value;
            app.currentDeathForm.reason_of_death = value;
            app.death_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
        end

        % Value changed function: death_notes
        function death_notesValueChanged(app, event)
            value = app.death_notes.Value;
            app.currentDeathForm.death_notes = value;
            app.death_ts.Value = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
        end

        % Button pushed function: ResetformsButton
        function ResetformsButtonPushed(app, event)
            switch app.mode
                case 'add'
                    empty = true;
                case 'update'
                    empty = false;
            end
            app.initializeForms(empty)
            app.initializeZygosityTable(empty)
        end

        % Value changing function: subject_id
        function subject_idValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Cell edit callback: ZygosityTable
        function ZygosityTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.ZygosityTable.Data{indices(1), 3} = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainSubjectTableFormPanel = uipanel(app.UIFigure);
            app.MainSubjectTableFormPanel.Title = 'Main Subject Table Form*';
            app.subject_idLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.subject_idLabel_2.Text = 'subject_id*';
            app.subject_id = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.subject_id.ValueChangedFcn = createCallbackFcn(app, @subject_idValueChanged, true);
            app.subject_id.ValueChangingFcn = createCallbackFcn(app, @subject_idValueChanging, true);
            app.dobLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.dobLabel_2.Text = 'dob*';
            app.dob = uidatepicker(app.MainSubjectTableFormPanel);
            app.dob.DisplayFormat = 'uuuu-MM-dd';
            app.dob.ValueChangedFcn = createCallbackFcn(app, @dobValueChanged, true);
            app.subject_tsLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.subject_tsLabel_2.Text = 'subject_ts';
            app.subject_ts = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.subject_ts.ValueChangedFcn = createCallbackFcn(app, @subject_tsValueChanged, true);
            app.sexLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.sexLabel_2.Text = 'sex*';
            app.sex = uidropdown(app.MainSubjectTableFormPanel);
            app.sex.ValueChangedFcn = createCallbackFcn(app, @sexValueChanged, true);
            app.mark_typeLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.mark_typeLabel_2.Text = 'mark_type*';
            app.mark_type = uidropdown(app.MainSubjectTableFormPanel);
            app.mark_type.ValueChangedFcn = createCallbackFcn(app, @mark_typeValueChanged, true);
            app.markEditFieldLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.markEditFieldLabel_2.Text = 'mark';
            app.mark = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.mark.ValueChangedFcn = createCallbackFcn(app, @markValueChanged, true);
            app.strain_nameLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.strain_nameLabel_2.Text = 'strain_name*';
            app.strain_name = uidropdown(app.MainSubjectTableFormPanel);
            app.strain_name.ValueChangedFcn = createCallbackFcn(app, @strain_nameValueChanged, true);
            app.source_nameLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.source_nameLabel_2.Text = 'source_name*';
            app.source_name = uidropdown(app.MainSubjectTableFormPanel);
            app.source_name.ValueChangedFcn = createCallbackFcn(app, @source_nameValueChanged, true);
            app.breeding_pairEditFieldLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.breeding_pairEditFieldLabel_2.Text = 'breeding_pair';
            app.breeding_pair = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.breeding_pair.ValueChangedFcn = createCallbackFcn(app, @breeding_pairValueChanged, true);
            app.subject_notesEditFieldLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.subject_notesEditFieldLabel_2.Text = 'subject_notes';
            app.subject_notes = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.subject_notes.ValueChangedFcn = createCallbackFcn(app, @subject_notesValueChanged, true);
            app.AddamouseButton = uibutton(app.UIFigure, 'push');
            app.AddamouseButton.Text = 'Add a mouse';
            app.SubmiteditsButton = uibutton(app.UIFigure, 'push');
            app.SubmiteditsButton.ButtonPushedFcn = createCallbackFcn(app, @SubmiteditsButtonPushed, true);
            app.SubmiteditsButton.Text = 'Submit edits';
            app.ResetformsButton = uibutton(app.UIFigure, 'push');
            app.ResetformsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetformsButtonPushed, true);
            app.ResetformsButton.Text = 'Reset forms';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.ZygosityTable = uitable(app.UIFigure);
            app.ZygosityTable.ColumnName = {'allele_name'; 'zygosity'; 'zygosity_ts'};
            app.ZygosityTable.RowName = {};
            app.ZygosityTable.ColumnEditable = true;
            app.ZygosityTable.CellEditCallback = createCallbackFcn(app, @ZygosityTableCellEdit, true);
            app.ZygosityLabel = uilabel(app.UIFigure);
            app.ZygosityLabel.Text = 'Zygosity';
            app.DeathPanel = uipanel(app.UIFigure);
            app.DeathPanel.Title = 'Death';
            app.dodLabel = uilabel(app.DeathPanel);
            app.dodLabel.Text = 'dod*';
            app.dod = uidatepicker(app.DeathPanel);
            app.dod.DisplayFormat = 'uuuu-MM-dd';
            app.dod.ValueChangedFcn = createCallbackFcn(app, @dodValueChanged, true);
            app.reason_of_deathDropDownLabel = uilabel(app.DeathPanel);
            app.reason_of_deathDropDownLabel.Text = 'reason_of_death*';
            app.reason_of_death = uidropdown(app.DeathPanel);
            app.reason_of_death.ValueChangedFcn = createCallbackFcn(app, @reason_of_deathValueChanged, true);
            app.death_tsEditFieldLabel = uilabel(app.DeathPanel);
            app.death_tsEditFieldLabel.Text = 'death_ts';
            app.death_ts = uieditfield(app.DeathPanel, 'text');
            app.death_notesEditFieldLabel = uilabel(app.DeathPanel);
            app.death_notesEditFieldLabel.Text = 'death_notes';
            app.death_notes = uieditfield(app.DeathPanel, 'text');
            app.death_notes.ValueChangedFcn = createCallbackFcn(app, @death_notesValueChanged, true);
            app.NicknamePanel = uipanel(app.UIFigure);
            app.NicknamePanel.Title = 'Nickname';
            app.nicknameLabel = uilabel(app.NicknamePanel);
            app.nicknameLabel.Text = 'nickname*';
            app.nickname = uieditfield(app.NicknamePanel, 'text');
            app.nickname.ValueChangedFcn = createCallbackFcn(app, @nicknameValueChanged2, true);
            app.OptionaltablesLabel = uilabel(app.UIFigure);
            app.OptionaltablesLabel.Text = 'Optional tables';
            app.TitleTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea.Value = {'New Subject'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditSubjectDialogue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end