classdef AddStrainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StrainRecordTable         matlab.ui.control.Table
        FillinastrainrecordLabel  matlab.ui.control.Label
        InsertrecordButton        matlab.ui.control.Button
        MessageTextAreaLabel      matlab.ui.control.Label
        MessageTextArea           matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CallingApp  % main app object
        query
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.query = hillman_mouse_subject.Strain;
            app.StrainRecordTable.ColumnName = app.query.header.names;
            app.StrainRecordTable.ColumnEditable = true(size(app.query.header.names));
            app.StrainRecordTable.Data = {'', '', ''};
            
        end

        % Cell edit callback: StrainRecordTable
        function StrainRecordTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: InsertrecordButton
        function InsertrecordButtonPushed(app, event)
            for i = 1:length(app.StrainRecordTable.ColumnName)
                name = app.StrainRecordTable.ColumnName{i};
                value = app.StrainRecordTable.Data{i};
                if ~isempty(value)
                    entry.(name) = value;
                end
            end
            try
                insert(hillman_mouse_subject.Strain, entry)
                app.MessageTextArea.Value = 'Success!';
                app.StrainRecordTable.Data = {'', '', ''};
                updateStrainTable(app.CallingApp)
            catch e
                app.MessageTextArea.Value = ['The following error happens during insertion, please edit record: ', e.message];
            end
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            app.CallingApp.AddStrainButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.StrainRecordTable = uitable(app.UIFigure);
            app.StrainRecordTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StrainRecordTable.RowName = {};
            app.StrainRecordTable.CellEditCallback = createCallbackFcn(app, @StrainRecordTableCellEdit, true);
            app.FillinastrainrecordLabel = uilabel(app.UIFigure);
            app.FillinastrainrecordLabel.Text = 'Fill in a strain record';
            app.InsertrecordButton = uibutton(app.UIFigure, 'push');
            app.InsertrecordButton.ButtonPushedFcn = createCallbackFcn(app, @InsertrecordButtonPushed, true);
            app.InsertrecordButton.Text = 'Insert record';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddStrainApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end