classdef AddSubjectDialogue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainSubjectTableFormPanel      matlab.ui.container.Panel
        subject_idLabel_2              matlab.ui.control.Label
        subject_id                     matlab.ui.control.EditField
        dobLabel_2                     matlab.ui.control.Label
        dob                            matlab.ui.control.DatePicker
        subject_tsLabel_2              matlab.ui.control.Label
        subject_ts                     matlab.ui.control.EditField
        sexLabel_2                     matlab.ui.control.Label
        sex                            matlab.ui.control.DropDown
        mark_typeLabel_2               matlab.ui.control.Label
        mark_type                      matlab.ui.control.DropDown
        markEditFieldLabel_2           matlab.ui.control.Label
        mark                           matlab.ui.control.EditField
        strain_nameLabel_2             matlab.ui.control.Label
        strain_name                    matlab.ui.control.DropDown
        source_nameLabel_2             matlab.ui.control.Label
        source_name                    matlab.ui.control.DropDown
        breeding_pairEditFieldLabel_2  matlab.ui.control.Label
        breeding_pair                  matlab.ui.control.EditField
        subject_notesEditFieldLabel_2  matlab.ui.control.Label
        subject_notes                  matlab.ui.control.EditField
        AddamouseButton                matlab.ui.control.Button
        SubmiteditsButton              matlab.ui.control.Button
        ResetformsButton               matlab.ui.control.Button
        MessageTextAreaLabel           matlab.ui.control.Label
        MessageTextArea                matlab.ui.control.TextArea
        ZygosityTable                  matlab.ui.control.Table
        ZygosityLabel                  matlab.ui.control.Label
        DeathPanel                     matlab.ui.container.Panel
        dodLabel                       matlab.ui.control.Label
        dod                            matlab.ui.control.DatePicker
        reason_of_deathDropDownLabel   matlab.ui.control.Label
        reason_of_death                matlab.ui.control.DropDown
        death_tsEditFieldLabel         matlab.ui.control.Label
        death_ts                       matlab.ui.control.EditField
        death_notesEditFieldLabel      matlab.ui.control.Label
        death_notes                    matlab.ui.control.EditField
        NicknamePanel                  matlab.ui.container.Panel
        nicknameLabel                  matlab.ui.control.Label
        nickname                       matlab.ui.control.EditField
        OptionaltablesLabel            matlab.ui.control.Label
        TitleTextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        conn
        CallingApp
        currentSubjectForm % currentSubjectForm
        currentNicknameForm
        currentDeathForm
        currentZygosityData
        subjectColumnNames
        nicknameColumnNames
        deathColumnNames
        zygosityColumnNames
        formElements % mapping of column names and GUI elements
        selectedSubjectKey
        selected = false
        mode
    end
    
    methods (Access = private)
       
        function createFormElements(app, tableName)
           
            switch tableName 
                case 'subject'
                    table = subject.Subject;
                case 'nickname'
                    table = subject.Nickname;
                case 'death'
                    table = subject.Death; 
            end
            
            columnNames = app.([tableName 'ColumnNames']);
            
            for iColumn = 1:length(columnNames)
                column = columnNames{iColumn};
               
                if isprop(app, column)
                    if isempty(app.formElements)
                        app.formElements(1).name = column;
                        app.formElements(1).element = app.(column);
                        app.formElements(1).table = table;
                    else
                        elementNames = {app.formElements.name};
                        % skip any columns that already in the elements
                        if ~any(cellfun(@isequal, elementNames, repmat({column}, size(elementNames))))
                            app.formElements(end+1) = struct(...
                                'name', column, ...
                                'element', app.(column), ...
                                'table', table);
                        end
                    end
                end
            end
            
        end
        
        function initializeForms(app)
            
            if app.selected
                subject_entry = fetch(subject.Subject & app.selectedSubjectKey, '*');
                nickname_entry = fetch(subject.Nickname & app.selectedSubjectKey, '*');
                death_entry = fetch(subject.Death & app.selectedSubjectKey, '*');
            end
            
            for field = app.formElements                
                ele = field.element;
                tableName = extractAfter(field.table.className, '.');
                
                % get options if the current element is a dropdown list
                if isa(ele, 'matlab.ui.control.DropDown')
                    ele.Items = utils.getOptions(field.table, field.name, app.conn);
                    ele.Items(end+1) = {''};
                    app.(['current' tableName 'Form']).(field.name) = '';
                    ele.Value = '';
                end
                
                if app.selected
                    switch tableName
                        case 'Subject'
                            entry = subject_entry;
                        case 'Nickname'
                            entry = nickname_entry;
                        case 'Death'
                            entry = death_entry;
                    end
                end
                
                if app.selected && length(entry)
                    % load the forms with selected field
                    if isfield(entry, field.name)
                        field_value = entry.(field.name);
                    else
                        field_value = '';
                    end
                    if isa(ele, 'matlab.ui.control.DatePicker')
                        if isempty(field_value)
                            field_value = NaT;
                        else
                            field_value = datetime(field_value,'InputFormat','yyyy-MM-dd');
                        end
                    end
                    ele.Value = field_value;
                    app.(['current' tableName 'Form']).(field.name) = field_value;
                    
                    if strcmp(app.mode, 'add') && contains(field.name, '_ts')
                        timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                        ele.Value = timeNowStr;
                        app.(['current' tableName 'Form']).(field.name) = timeNowStr;
                    end
                       
                else
                    % get default values and insert data into the corresponding
                    % GUI elements
                    default = utils.getDefaultValue(field.table, field.name);
                    if ~isempty(default)
                        ele.Value = default;
                        app.(['current' tableName 'Form']).(field.name) = default;
                    elseif contains(field.name, '_ts')
                        timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                        ele.Value = timeNowStr;
                        app.(['current' tableName 'Form']).(field.name) = timeNowStr;
                    else
                        if isa(ele, 'matlab.ui.control.DatePicker')
                            ele.Value = NaT;
                        else
                            ele.Value = '';
                        end
                        
                    end
                end
                
                
            end
        end
        
        function initializeZygosityTable(app)
            
           if app.selected
               zygosity_entries = fetch(subject.Zygosity & app.selectedSubjectKey, '*');
               zygosity_entries = rmfield(zygosity_entries, 'subject_id');
           end
          
           columnNames = app.zygosityColumnNames(~strcmp(app.zygosityColumnNames, 'subject_id'));
           
           % five empty rows
           nRows = 5;
           app.ZygosityTable.Data = repmat({''}, nRows, length(columnNames));
           
           % if selected and entries not empty, load the first few rows
           % with existing entries
           if app.selected && length(zygosity_entries)
                app.ZygosityTable.Data(1:length(zygosity_entries), :) = struct2cell(zygosity_entries)';
                
           end
           
           app.ZygosityTable.ColumnFormat = repmat({[]}, 1, length(columnNames));
           
           
           for iColumn = 1:length(columnNames)
               
                column = columnNames{iColumn};
                options = utils.getOptions(subject.Zygosity, column, app.conn);
                if ~isempty(options)
                    app.ZygosityTable.ColumnFormat{iColumn} = options;
                end
                if contains(column, '_ts') && (strcmp(app.mode, 'add') || iColumn > length(zygosity_entries))
                    timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                    app.ZygosityTable.Data(:, iColumn) = repmat({timeNowStr}, nRows, 1);
                end
                
           end
           
        end
        
        function insertRecords(app)
            
            subj_entry = gui_utils.cleanupEntry(app.currentSubjectForm);
            
            try
                insert(subject.Subject, subj_entry)
                app.MessageTextArea.Value = 'Successfully inserted Subject record!';
            catch exception                
                app.MessageTextArea.Value = sprintf('Error when inserting the Subject entry: %s', exception.message);
            end
            
            % if nickname form is not empty
            if ~isempty(app.currentNicknameForm) && ~isempty(app.currentNicknameForm.nickname)
                nickname_entry = gui_utils.cleanupEntry(struct('subject_id', app.currentSubjectForm.subject_id, ...
                    'nickname', app.currentNicknameForm.nickname));
                
                 try
                     insert(subject.Nickname, nickname_entry)
                     app.MessageTextArea.Value{end+1} = 'Successfully inserted Nickname record!';
                 catch exception
                     app.MessageTextArea.Value{end+1} = ...
                         sprintf('Error when inserting the Nickname entry: %s', exception.message);
                 end
            end
            
            % if death form is not empty
            if ~isempty(app.currentDeathForm) && isfield(app.currentDeathForm, 'dod') && ~isempty(app.currentDeathForm.dod)
                death_entry = app.currentDeathForm;
                death_entry.subject_id = app.currentSubjectForm.subject_id;
                death_entry = gui_utils.cleanupEntry(death_entry);
                try
                    insert(subject.Death, death_entry)
                    app.MessageTextArea.Value
                    app.MessageTextArea.Value{end+1} = 'Successfully inserted Death record!';
                catch exception
                    app.MessageTextArea.Value
                    app.MessageTextArea.Value{end+1} = ...
                        sprintf('Error when inserting the Death entry: %s', exception.message);
                end
            end
            
            % insert Zygosity
            zygosity_entries = struct();
            for iData = app.ZygosityTable.Data'
                
                if ~isempty(iData{1})
                    new_entry = struct('subject_id', app.currentSubjectForm.subject_id);
                    for iField = 1:length(iData)
                        new_entry.(app.zygosityColumnNames{iField+1}) = iData{iField};
                    end
                    new_entry = gui_utils.cleanupEntry(new_entry);
                    if isempty(fields(zygosity_entries))
                        zygosity_entries = new_entry;
                    else
                        zygosity_entries(end+1) = new_entry;
                    end
                end
            end
            
            if ~isempty(fields(zygosity_entries))
                try
                    insert(subject.Zygosity, zygosity_entries)
                    app.MessageTextArea.Value{end+1} = 'Successfully inserted Zygosity records!';
                catch exception
                    app.MessageTextArea.Value{end+1} = ...
                        sprintf('Error when inserting the Zygostiy entries: %s', exception.message);
                end
            end
        end
        
        function updateRecords(app)
            
            dj.config('safemode', false)
            oldSubjectEntry = fetch(subject.Subject & app.selectedSubjectKey, '*');
            newSubjectEntry = gui_utils.cleanupEntry(app.currentSubjectForm);
            newSubjectFields = fields(newSubjectEntry);
            
            % update the main table
            for field = fields(oldSubjectEntry)'
                f = field{1};
                if ~contains([newSubjectFields{:}], f) && ~strcmp(f, 'species')
                    % if a field is missing in an entry, restore it to the
                    % default value
                    newValue = utils.getDefaultValue(subject.Subject, f);
                    if isequal(oldSubjectEntry.(f), newValue)
                        continue
                    end
                elseif strcmp(f, 'species')
                    continue
                elseif ~isequal(oldSubjectEntry.(f), newSubjectEntry.(f))
                    newValue = newSubjectEntry.(f);
                else
                    continue
                end
                
                try
                    update(subject.Subject & app.selectedSubjectKey, f, newValue)
                    % TODO: show the old value and new value in the message
                    % as well, but have to deal with different data types
                    app.MessageTextArea.Value{end+1} = ...
                       sprintf('Successfully updated field "%s"!', f);
                    
                    timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                    update(subject.Subject & app.selectedSubjectKey, 'subject_ts', timeNowStr)
                    app.subject_ts.Value = timeNowStr;
                catch exception                
                    app.MessageTextArea.Value{end+1} = ...
                        sprintf('Error when updating the field %s: %s', f, exception.message);
                end
                
            end
            
            % update nickname table
            if isempty(app.currentNicknameForm) || isempty(app.currentNicknameForm.nickname)
                if ~isempty(fetch(subject.Nickname & app.selectedSubjectKey))
                    
                    oldNickname = fetch(subject.Nickname & app.selectedSubjectKey, 'nickname');
                    try
                        del(subject.Nickname & app.selectedSubjectKey)
                        app.MessageTextArea.Value{end+1} = ...
                            sprintf('Successfully deleted nickname record {"nickname": %s}!', oldNickname);
                    catch exception
                        app.MessageTextArea.Value{end+1} = ...
                            sprintf('Error when deleting the nickname record {"nickname": %s}: %s', oldNickname, exception.message);
                    end
                end
            else
                if ~isempty(fetch(subject.Nickname & app.selectedSubjectKey))
                    oldNickname = fetch(subject.Nickname & app.selectedSubjectKey, 'nickname');
                    if ~strcmp(oldNickname, app.currentNicknameForm.nickname)
                        try
                            del(subject.Nickname & app.selectedSubjectKey)
                            app.MessageTextArea.Value{end+1} = ...
                                sprintf('Successfully deleted nickname record {"nickname": %s}!', oldNickname.nickname);
                            insert(subject.Nickname, ...
                               struct('subject_id', app.currentSubjectForm.subject_id, ...
                                      'nickname', app.currentNicknameForm.nickname))
                            app.MessageTextArea.Value{end+1} = ...
                                sprintf('Successfully inserted nickname record {"nickname": %s}!', ...
                                        app.currentNicknameForm.nickname);
                        catch exception
                            app.MessageTextArea.Value{end+1} = ...
                                sprintf('Error when updating the nickname field {"nickname": %s}: %s', ...
                                        oldNickname.nickname, ...
                                        exception.message);
                        end
                    end
                else
                    try
                        insert(subject.Nickname, ...
                               struct('subject_id', app.currentSubjectForm.subject_id, ...
                                      'nickname', app.currentNicknameForm.nickname))
                                  
                        app.MessageTextArea.Value{end+1} = ...
                                sprintf('Successfully inserted nickname record {"nickname": %s}!', ...
                                        app.currentNicknameForm.nickname);
                    catch exception
                        app.MessageTextArea.Value{end+1} = ...
                            printf('Error when inserting the nickname field: %s', exception.message);
                        
                    end
                end
            end
            
            % update Death table
            if isempty(app.currentDeathForm) || isempty(app.currentDeathForm.dod)
                if ~isempty(fetch(subject.Death & app.selectedSubjectKey))
                    try
                        del(subject.Death & app.selectedSubjectKey)
                        app.MessageTextArea.Value{end+1} = ...
                            sprintf('Successfully deleted death record!');
                    catch exception
                        app.MessageTextArea.Value{end+1} = ...
                            sprintf('Error when deleting the death record: %s', f, exception.message);
                    end
                end
            else
                newDeathEntry = gui_utils.cleanupEntry(app.currentDeathForm);
                newDeathEntry.subject_id = app.currentSubjectForm.subject_id;
                if ~isempty(fetch(subject.Death & app.selectedSubjectKey))
                    oldDeathEntry = fetch(subject.Death & app.selectedSubjectKey, '*');
                    if ~strcmp(oldDeathEntry.dod, newDeathEntry.dod)
                        try
                            del(subject.Death & app.selectedSubjectKey)
                            app.MessageTextArea.Value{end+1} = ...
                                sprintf('Successfully deleted death record {"dod": %s}!', oldDeathEntry.dod);
                            timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                            newDeathEntry.death_ts = timeNowStr;
                            insert(subject.Death, newDeathEntry)
                            app.MessageTextArea.Value{end+1} = ...
                                sprintf('Successfully inserted new death record {"dod": %s}!', newDeathEntry.dod);
                            app.death_ts.Value = timeNowStr;
                        catch exception
                            app.MessageTextArea.Value{end+1} = ...
                                sprintf('Error when updating the death record: %s', exception.message);
                        end
                    else
                        
                        newDeathFields = fields(newDeathEntry);
                        for field = fields(oldDeathEntry)'
                            f = field{1};
                            if contains(f, '_ts')
                                continue
                            end
                            if ~contains([newDeathFields{:}], f)
                                newValue = utils.getDefaultValue(subject.Death, f);
                                if strcmp(oldDeathEntry.(f), newValue)
                                    continue
                                end
                            else
                                if ~isequal(oldDeathEntry.(f), newDeathEntry.(f))
                                    newValue = newDeathEntry.(f);
                                else
                                    continue
                                end
                            end
                                    
                            try
                                update(subject.Death & app.selectedSubjectKey, f, newValue)
                                % TODO: show the old value and new value in the message
                                % as well, but have to deal with different data types
                                app.MessageTextArea.Value{end+1} = ...
                                   sprintf('Successfully updated death field "%s"!', f);
                                
                                timeNowStr = datestr(datetime('now'), 'yyyy-mm-dd HH:MM:ss');
                                update(subject.Death & app.selectedSubjectKey, 'death_ts', timeNowStr)
                                app.death_ts.Value = timeNowStr;
                            catch exception                
                                app.MessageTextArea.Value{end+1} = ...
                                    sprintf('Error when updating the death field %s: %s', f, exception.message);
                            end
                               
                        end
                    end
                else
                    try
                        newDeathEntry.subject_id = app.currentSubjectForm.subject_id;
                        insert(subject.Death, newDeathEntry)
                                  
                        app.MessageTextArea.Value{end+1} = ...
                                sprintf('Successfully inserted death record!');
                    catch exception
                        app.MessageTextArea.Value{end+1} = ...
                            printf('Error when inserting the nickname field: %s', exception.message);
                        
                    end
                end
            end
            
            % update Zygosity table
            
            dj.config('safemode', true)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mode)
             % Store main app in property for CloseRequestFcn to use
            if nargin < 3
                app.mode = 'add';
            end
            app.mode = mode;
            app.CallingApp = mainapp;
            app.conn = mainapp.conn;
            app.selectedSubjectKey = mainapp.selectedSubjectKey;
            
            if ~isempty(fetch(subject.Subject & app.selectedSubjectKey))
                app.selected = true;
            end
            
            app.subjectColumnNames = mainapp.subjectColumnNames;
            app.nicknameColumnNames = mainapp.nicknameColumnNames;
            app.zygosityColumnNames = mainapp.zygosityColumnNames;
            app.deathColumnNames = mainapp.deathColumnNames;
            for table = {'subject', 'nickname', 'death'}
                app.createFormElements(table{1})
            end
            
            app.initializeForms();
            app.initializeZygosityTable();
            
            switch app.mode
                case 'add'
                    app.TitleTextArea.Value = {'New Subject'};
                case 'update'
                    app.TitleTextArea.Value = {'Update Subject'};
                    app.subject_id.Editable = false;
                    app.ResetformsButton.Enable = 'off';
            end
                
        end

        % Button pushed function: SubmiteditsButton
        function SubmiteditsButtonPushed(app, event)
            
            switch app.mode
                case 'add'
                    app.insertRecords()
                case 'update'
                    app.updateRecords()
            end
            app.CallingApp.SubjectTable.Data = struct2cell(fetch(subject.Subject, '*'))';
        end

        % Value changed function: subject_id
        function subject_idValueChanged(app, event)
            value = app.subject_id.Value;
            app.currentSubjectForm.subject_id = value;
        end

        % Callback function
        function subject_colony_nameValueChanged(app, event)
            value = app.subject_colony_name.Value;
            app.currentSubjectForm.subject_colony_name = value;
        end

        % Callback function
        function nicknameValueChanged(app, event)
            value = app.nickname.Value;
            app.currentSubjectForm.nickname = value;
        end

        % Value changed function: dob
        function dobValueChanged(app, event)
            value = app.dob.Value;
            app.currentSubjectForm.dob = value;
        end

        % Value changed function: sex
        function sexValueChanged(app, event)
            value = app.sex.Value;
            app.currentSubjectForm.sex = value;
        end

        % Value changed function: strain_name
        function strain_nameValueChanged(app, event)
            value = app.strain_name.Value;
            app.currentSubjectForm.strain_name = value;
            alleles = fetch(subject.StrainAllele & struct('strain_name', value));
            if isempty(alleles)
                app.ZygosityTable.ColumnFormat{1} = [];
            else
                app.ZygosityTable.ColumnFormat{1} = fetchn(subject.StrainAllele & alleles, 'allele_name')';
            end
        end

        % Value changed function: mark_type
        function mark_typeValueChanged(app, event)
            value = app.mark_type.Value;
            app.currentSubjectForm.mark_type = value;
        end

        % Value changed function: mark
        function markValueChanged(app, event)
            value = app.mark.Value;
            app.currentSubjectForm.mark = value;
        end

        % Value changed function: source_name
        function source_nameValueChanged(app, event)
            value = app.source_name.Value;
            app.currentSubjectForm.source_name = value;
        end

        % Value changed function: breeding_pair
        function breeding_pairValueChanged(app, event)
            value = app.breeding_pair.Value;
            app.currentSubjectForm.breeding_pair = value;
        end

        % Value changed function: subject_ts
        function subject_tsValueChanged(app, event)
            value = app.subject_ts.Value;
            app.currentSubjectForm.subject_ts = value;
        end

        % Value changed function: subject_notes
        function subject_notesValueChanged(app, event)
            value = app.subject_notes.Value;
            app.currentSubjectForm.subject_notes = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.AddSubjectButton.Enable = 'on';
            if app.selected
                app.CallingApp.UpdateSubjectButton.Enable = 'on';
                app.CallingApp.DeleteSubjectButton.Enable = 'on';
            end
            delete(app)      
        end

        % Value changed function: nickname
        function nicknameValueChanged2(app, event)
            value = app.nickname.Value;
            app.currentNicknameForm.nickname = value;
        end

        % Value changed function: dod
        function dodValueChanged(app, event)
            value = app.dod.Value;
            app.currentDeathForm.dod = value;
        end

        % Value changed function: reason_of_death
        function reason_of_deathValueChanged(app, event)
            value = app.reason_of_death.Value;
            app.currentDeathForm.reason_of_death = value;
        end

        % Value changed function: death_notes
        function death_notesValueChanged(app, event)
            value = app.death_notes.Value;
            app.currentDeathForm.death_notes = value;
        end

        % Button pushed function: ResetformsButton
        function ResetformsButtonPushed(app, event)
            app.initializeForms()
            app.initializeZygosityTable()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainSubjectTableFormPanel = uipanel(app.UIFigure);
            app.MainSubjectTableFormPanel.Title = 'Main Subject Table Form*';
            app.subject_idLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.subject_idLabel_2.Text = 'subject_id*';
            app.subject_id = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.subject_id.ValueChangedFcn = createCallbackFcn(app, @subject_idValueChanged, true);
            app.dobLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.dobLabel_2.Text = 'dob*';
            app.dob = uidatepicker(app.MainSubjectTableFormPanel);
            app.dob.DisplayFormat = 'uuuu-MM-dd';
            app.dob.ValueChangedFcn = createCallbackFcn(app, @dobValueChanged, true);
            app.subject_tsLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.subject_tsLabel_2.Text = 'subject_ts';
            app.subject_ts = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.subject_ts.ValueChangedFcn = createCallbackFcn(app, @subject_tsValueChanged, true);
            app.sexLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.sexLabel_2.Text = 'sex*';
            app.sex = uidropdown(app.MainSubjectTableFormPanel);
            app.sex.ValueChangedFcn = createCallbackFcn(app, @sexValueChanged, true);
            app.mark_typeLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.mark_typeLabel_2.Text = 'mark_type*';
            app.mark_type = uidropdown(app.MainSubjectTableFormPanel);
            app.mark_type.ValueChangedFcn = createCallbackFcn(app, @mark_typeValueChanged, true);
            app.markEditFieldLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.markEditFieldLabel_2.Text = 'mark';
            app.mark = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.mark.ValueChangedFcn = createCallbackFcn(app, @markValueChanged, true);
            app.strain_nameLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.strain_nameLabel_2.Text = 'strain_name*';
            app.strain_name = uidropdown(app.MainSubjectTableFormPanel);
            app.strain_name.ValueChangedFcn = createCallbackFcn(app, @strain_nameValueChanged, true);
            app.source_nameLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.source_nameLabel_2.Text = 'source_name*';
            app.source_name = uidropdown(app.MainSubjectTableFormPanel);
            app.source_name.ValueChangedFcn = createCallbackFcn(app, @source_nameValueChanged, true);
            app.breeding_pairEditFieldLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.breeding_pairEditFieldLabel_2.Text = 'breeding_pair';
            app.breeding_pair = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.breeding_pair.ValueChangedFcn = createCallbackFcn(app, @breeding_pairValueChanged, true);
            app.subject_notesEditFieldLabel_2 = uilabel(app.MainSubjectTableFormPanel);
            app.subject_notesEditFieldLabel_2.Text = 'subject_notes';
            app.subject_notes = uieditfield(app.MainSubjectTableFormPanel, 'text');
            app.subject_notes.ValueChangedFcn = createCallbackFcn(app, @subject_notesValueChanged, true);
            app.AddamouseButton = uibutton(app.UIFigure, 'push');
            app.AddamouseButton.Text = 'Add a mouse';
            app.SubmiteditsButton = uibutton(app.UIFigure, 'push');
            app.SubmiteditsButton.ButtonPushedFcn = createCallbackFcn(app, @SubmiteditsButtonPushed, true);
            app.SubmiteditsButton.Text = 'Submit edits';
            app.ResetformsButton = uibutton(app.UIFigure, 'push');
            app.ResetformsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetformsButtonPushed, true);
            app.ResetformsButton.Text = 'Reset forms';
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.ZygosityTable = uitable(app.UIFigure);
            app.ZygosityTable.ColumnName = {'allele_name'; 'zygosity'; 'zygosity_ts'};
            app.ZygosityTable.RowName = {};
            app.ZygosityTable.ColumnEditable = true;
            app.ZygosityLabel = uilabel(app.UIFigure);
            app.ZygosityLabel.Text = 'Zygosity';
            app.DeathPanel = uipanel(app.UIFigure);
            app.DeathPanel.Title = 'Death';
            app.dodLabel = uilabel(app.DeathPanel);
            app.dodLabel.Text = 'dod*';
            app.dod = uidatepicker(app.DeathPanel);
            app.dod.DisplayFormat = 'uuuu-MM-dd';
            app.dod.ValueChangedFcn = createCallbackFcn(app, @dodValueChanged, true);
            app.reason_of_deathDropDownLabel = uilabel(app.DeathPanel);
            app.reason_of_deathDropDownLabel.Text = 'reason_of_death*';
            app.reason_of_death = uidropdown(app.DeathPanel);
            app.reason_of_death.ValueChangedFcn = createCallbackFcn(app, @reason_of_deathValueChanged, true);
            app.death_tsEditFieldLabel = uilabel(app.DeathPanel);
            app.death_tsEditFieldLabel.Text = 'death_ts';
            app.death_ts = uieditfield(app.DeathPanel, 'text');
            app.death_notesEditFieldLabel = uilabel(app.DeathPanel);
            app.death_notesEditFieldLabel.Text = 'death_notes';
            app.death_notes = uieditfield(app.DeathPanel, 'text');
            app.death_notes.ValueChangedFcn = createCallbackFcn(app, @death_notesValueChanged, true);
            app.NicknamePanel = uipanel(app.UIFigure);
            app.NicknamePanel.Title = 'Nickname';
            app.nicknameLabel = uilabel(app.NicknamePanel);
            app.nicknameLabel.Text = 'nickname*';
            app.nickname = uieditfield(app.NicknamePanel, 'text');
            app.nickname.ValueChangedFcn = createCallbackFcn(app, @nicknameValueChanged2, true);
            app.OptionaltablesLabel = uilabel(app.UIFigure);
            app.OptionaltablesLabel.Text = 'Optional tables';
            app.TitleTextArea = uitextarea(app.UIFigure);
            app.TitleTextArea.Value = {'New Subject'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddSubjectDialogue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end