classdef HillmanlabMouseGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LabTab                       matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        SourceLabel                  matlab.ui.control.Label
        AddSourceButton              matlab.ui.control.Button
        SubjectTab                   matlab.ui.container.Tab
        subject_idDropDownLabel      matlab.ui.control.Label
        subject_idDropDown           matlab.ui.control.DropDown
        SubjectListTable             matlab.ui.control.Table
        AddStrainButton              matlab.ui.control.Button
        AddamouseButton              matlab.ui.control.Button
        StrainListTable              matlab.ui.control.Table
        StrainListLabel              matlab.ui.control.Label
        SubjectListLabel             matlab.ui.control.Label
        UpdateStrainButton           matlab.ui.control.Button
        LoadthemouserecordButton     matlab.ui.control.Button
        NewSubjectLabel              matlab.ui.control.Label
        SubjectformPanel             matlab.ui.container.Panel
        subject_idLabel              matlab.ui.control.Label
        subject_id                   matlab.ui.control.EditField
        subject_colony_nameLabel     matlab.ui.control.Label
        subject_colony_name          matlab.ui.control.EditField
        dobLabel                     matlab.ui.control.Label
        dob                          matlab.ui.control.DatePicker
        subject_tsLabel              matlab.ui.control.Label
        subject_ts                   matlab.ui.control.EditField
        sexLabel                     matlab.ui.control.Label
        sex                          matlab.ui.control.DropDown
        mark_typeLabel               matlab.ui.control.Label
        mark_type                    matlab.ui.control.DropDown
        markEditFieldLabel           matlab.ui.control.Label
        mark                         matlab.ui.control.EditField
        strain_nameLabel             matlab.ui.control.Label
        strain_name                  matlab.ui.control.DropDown
        source_nameLabel             matlab.ui.control.Label
        source_name                  matlab.ui.control.DropDown
        breeding_pairEditFieldLabel  matlab.ui.control.Label
        breeding_pair                matlab.ui.control.EditField
        nicknameLabel                matlab.ui.control.Label
        nickname                     matlab.ui.control.EditField
        subject_notesEditFieldLabel  matlab.ui.control.Label
        subject_notes                matlab.ui.control.EditField
        FlexiblequeryEditFieldLabel  matlab.ui.control.Label
        FlexiblequeryEditField       matlab.ui.control.EditField
        nicknameDropDownLabel        matlab.ui.control.Label
        nicknameDropDown             matlab.ui.control.DropDown
        bornbeforeDatePickerLabel    matlab.ui.control.Label
        bornbeforeDatePicker         matlab.ui.control.DatePicker
        bornafterDatePickerLabel     matlab.ui.control.Label
        bornafterDatePicker          matlab.ui.control.DatePicker
        strainDropDownLabel          matlab.ui.control.Label
        strainDropDown               matlab.ui.control.DropDown
        MessageTextAreaLabel         matlab.ui.control.Label
        MessageTextArea              matlab.ui.control.TextArea
        SubmitfilterButton           matlab.ui.control.Button
        ResetfilterButton            matlab.ui.control.Button
        DeletethemouserecordButton   matlab.ui.control.Button
        CleartheformButton           matlab.ui.control.Button
        UpdatethemouserecordButton   matlab.ui.control.Button
        SurgeryTab                   matlab.ui.container.Tab
        Tab                          matlab.ui.container.Tab
    end

    
    properties (Access = private)
        AddStrainApp            % Dialogue pop up window when clicking add strain
        conn                    % dj connection
        strains
        subject_tables = {subject.Subject, subject.Nickname}
        q_subjects              % joined product of subject related tables
        subjects                % data shown in the subject table at the moment
        selected_subject        % selected subject as a struct
        subject_form_elements   % mapping of column names and GUI elements
        current_subject_form    % struct array that tracks the form values
        subject_dependent_fields = {'strain_name', 'source_name'}  % fields dependent on other tables
    end
    
    methods (Access = public)
        
        function updateStrainTable(app)
            
            app.strains = fetch(subject.Strain, '*');
            if ~isempty(app.strains)
                app.StrainListTable.Data = struct2cell(app.strains)';
            else
                app.StrainListTable.Data = {};
            end
        end
    end
    
    methods (Access = private)
        
        function updateSubjectForm(app)
            
            
        end
        
        function initializeSubjectForm(app)
            
            for field = app.subject_form_elements                
                ele = field.element;
                % get options if the current element is a dropdown list
                if isa(ele, 'matlab.ui.control.DropDown')
                    ele.Items = utils.getOptions(field.table, field.name, app.conn);
                    app.current_subject_form.(field.name) = '';
                end
                
                % get default values and insert data into the corresponding
                % GUI elements
                default = utils.getDefaultValue(app.q_subjects, field.name);
                if ~isempty(default)
                    app.current_subject_form.(field.name) = default;
                    ele.Value = default;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.conn = dj.conn('127.0.0.1', 'shan', 'shan');
            
            app.TabGroup.SelectedTab = app.SubjectTab;
            
            app.strains = fetch(subject.Strain, '*');
            strain_columns = fieldnames(app.strains);
            app.StrainListTable.ColumnName = strain_columns;
            
            if ~isempty(app.strains)
                app.StrainListTable.Data = struct2cell(app.strains)';
            end
            
            app.UpdateStrainButton.Enable = 'off';
            
            app.q_subjects = subject.Subject * subject.Nickname;
        
            app.subjects = fetch(app.q_subjects, '*');
            subject_columns = fieldnames(app.subjects);
            app.SubjectListTable.ColumnName = subject_columns;
            
            if ~isempty(app.subjects)
                app.SubjectListTable.Data = struct2cell(app.subjects)';
            end
            
            % struct array for the mapping of the field, table and GUI
            % elements
            
            idx = 0;
            for i_column = 1:length(subject_columns)
                column = subject_columns{i_column};
                if isprop(app, column)
                    idx = idx + 1;
                    app.subject_form_elements(idx).name = column;
                    app.subject_form_elements(idx).element = app.(column);
                    for i_table = 1:length(app.subject_tables)
                        table = app.subject_tables{i_table};
                        app.subject_form_elements(idx).table = table;
                        break
                    end
                end
            end
            app.initializeSubjectForm()
            
        end

        % Button pushed function: AddStrainButton
        function AddStrainButtonPushed(app, event)
            % Disable the button while dialog is open
            app.AddStrainButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AddStrainApp = AddStrainDialogue(app);
        end

        % Button pushed function: AddamouseButton
        function AddamouseButtonPushed(app, event)
            for field = fields(app.current_subject_form)'
                f = field{1};
                if isempty(app.current_subject_form.(f))
                    app.current_subject_form = rmfield(app.current_subject_form, f);
                end
                if strcmp(f, 'dob')
                    app.current_subject_form.(f) = datestr(app.current_subject_form.(f), 'yyyy-mm-dd');
                end
            end
            subj_entry = app.current_subject_form;
            subj_entry = rmfield(subj_entry, 'nickname');
            
            nickname_entry.nickname = app.current_subject_form.nickname;
            nickname_entry.subject_id = app.current_subject_form.subject_id;
            insert(subject.Subject, subj_entry)
            insert(subject.Nickname, nickname_entry)
            
            app.subjects = fetch(app.q_subjects, '*');
            
            if ~isempty(app.strains)
                app.SubjectListTable.Data = struct2cell(app.subjects)';
            end
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            delete(app)
        end

        % Cell selection callback: StrainListTable
        function StrainListTableCellSelection(app, event)
            indices = event.Indices;
            app.UpdateStrainButton.Enable = 'on';
            selected_strains = app.strains(indices);
            
            
        end

        % Button pushed function: UpdateStrainButton
        function UpdateStrainButtonPushed(app, event)
            
        end

        % Button pushed function: AddSourceButton
        function AddSourceButtonPushed(app, event)
            
        end

        % Cell edit callback: StrainListTable
        function StrainListTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Display data changed function: SubjectListTable
        function SubjectListTableDisplayDataChanged(app, event)
            newDisplayData = app.SubjectListTable.DisplayData;
            
        end

        % Cell selection callback: SubjectListTable
        function SubjectListTableCellSelection(app, event)
            indices = event.Indices;
            app.selected_subject = app.subjects(indices(1));
            app.updateSubjectForm();
        end

        % Value changed function: subject_id
        function subject_idValueChanged(app, event)
            value = app.subject_id.Value;
            app.current_subject_form.subject_id = value;
        end

        % Value changed function: subject_colony_name
        function subject_colony_nameValueChanged(app, event)
            value = app.subject_colony_name.Value;
            app.current_subject_form.subject_colony_name = value;
        end

        % Value changed function: nickname
        function nicknameValueChanged(app, event)
            value = app.nickname.Value;
            app.current_subject_form.nickname = value;
        end

        % Value changed function: dob
        function dobValueChanged(app, event)
            value = app.dob.Value;
            app.current_subject_form.dob = value;
        end

        % Value changed function: sex
        function sexValueChanged(app, event)
            value = app.sex.Value;
            app.current_subject_form.sex = value;
        end

        % Value changed function: strain_name
        function strain_nameValueChanged(app, event)
            value = app.strain_name.Value;
            app.current_subject_form.strain_name = value;
        end

        % Value changed function: mark_type
        function mark_typeValueChanged(app, event)
            value = app.mark_type.Value;
            app.current_subject_form.mark_type = value;
        end

        % Value changed function: mark
        function markValueChanged(app, event)
            value = app.mark.Value;
            app.current_subject_form.mark = value;
        end

        % Value changed function: source_name
        function source_nameValueChanged(app, event)
            value = app.source_name.Value;
            app.current_subject_form.source_name = value;
        end

        % Value changed function: breeding_pair
        function breeding_pairValueChanged(app, event)
            value = app.breeding_pair.Value;
            app.current_subject_form.breeding_pair = value;
        end

        % Value changed function: subject_ts
        function subject_tsValueChanged(app, event)
            value = app.subject_ts.Value;
            app.current_subject_form.subject_ts = value;
        end

        % Value changed function: subject_notes
        function subject_notesValueChanged(app, event)
            value = app.subject_notes.Value;
            app.current_subject_form.subject_notes = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LabTab = uitab(app.TabGroup);
            app.LabTab.Title = 'Lab';
            app.UITable = uitable(app.LabTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SourceLabel = uilabel(app.LabTab);
            app.SourceLabel.Text = 'Source';
            app.AddSourceButton = uibutton(app.LabTab, 'push');
            app.AddSourceButton.ButtonPushedFcn = createCallbackFcn(app, @AddSourceButtonPushed, true);
            app.AddSourceButton.Text = 'Add a source';
            app.SubjectTab = uitab(app.TabGroup);
            app.SubjectTab.Title = 'Subject';
            app.subject_idDropDownLabel = uilabel(app.SubjectTab);
            app.subject_idDropDownLabel.Text = 'subject_id';
            app.subject_idDropDown = uidropdown(app.SubjectTab);
            app.SubjectListTable = uitable(app.SubjectTab);
            app.SubjectListTable.ColumnName = {'subject_id'; 'nickname'; 'dob'; 'sex'};
            app.SubjectListTable.RowName = {};
            app.SubjectListTable.CellSelectionCallback = createCallbackFcn(app, @SubjectListTableCellSelection, true);
            app.SubjectListTable.DisplayDataChangedFcn = createCallbackFcn(app, @SubjectListTableDisplayDataChanged, true);
            app.AddStrainButton = uibutton(app.SubjectTab, 'push');
            app.AddStrainButton.ButtonPushedFcn = createCallbackFcn(app, @AddStrainButtonPushed, true);
            app.AddStrainButton.Text = 'Add a strain';
            app.AddamouseButton = uibutton(app.SubjectTab, 'push');
            app.AddamouseButton.ButtonPushedFcn = createCallbackFcn(app, @AddamouseButtonPushed, true);
            app.AddamouseButton.Text = 'Add a mouse';
            app.StrainListTable = uitable(app.SubjectTab);
            app.StrainListTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StrainListTable.RowName = {};
            app.StrainListTable.CellEditCallback = createCallbackFcn(app, @StrainListTableCellEdit, true);
            app.StrainListTable.CellSelectionCallback = createCallbackFcn(app, @StrainListTableCellSelection, true);
            app.StrainListLabel = uilabel(app.SubjectTab);
            app.StrainListLabel.Text = 'Strain List';
            app.SubjectListLabel = uilabel(app.SubjectTab);
            app.SubjectListLabel.Text = 'Subject List';
            app.UpdateStrainButton = uibutton(app.SubjectTab, 'push');
            app.UpdateStrainButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateStrainButtonPushed, true);
            app.UpdateStrainButton.Text = 'Update strain records ';
            app.LoadthemouserecordButton = uibutton(app.SubjectTab, 'push');
            app.LoadthemouserecordButton.Text = 'Load the mouse record';
            app.NewSubjectLabel = uilabel(app.SubjectTab);
            app.NewSubjectLabel.Text = 'New Subject';
            app.SubjectformPanel = uipanel(app.SubjectTab);
            app.SubjectformPanel.Title = 'Subject form';
            app.subject_idLabel = uilabel(app.SubjectformPanel);
            app.subject_idLabel.Text = 'subject_id*';
            app.subject_id = uieditfield(app.SubjectformPanel, 'text');
            app.subject_id.ValueChangedFcn = createCallbackFcn(app, @subject_idValueChanged, true);
            app.subject_colony_nameLabel = uilabel(app.SubjectformPanel);
            app.subject_colony_nameLabel.Text = 'subject_colony_name*';
            app.subject_colony_name = uieditfield(app.SubjectformPanel, 'text');
            app.subject_colony_name.ValueChangedFcn = createCallbackFcn(app, @subject_colony_nameValueChanged, true);
            app.dobLabel = uilabel(app.SubjectformPanel);
            app.dobLabel.Text = 'dob*';
            app.dob = uidatepicker(app.SubjectformPanel);
            app.dob.DisplayFormat = 'uuuu-MM-dd';
            app.dob.ValueChangedFcn = createCallbackFcn(app, @dobValueChanged, true);
            app.subject_tsLabel = uilabel(app.SubjectformPanel);
            app.subject_tsLabel.Text = 'subject_ts';
            app.subject_ts = uieditfield(app.SubjectformPanel, 'text');
            app.subject_ts.ValueChangedFcn = createCallbackFcn(app, @subject_tsValueChanged, true);
            app.sexLabel = uilabel(app.SubjectformPanel);
            app.sexLabel.Text = 'sex*';
            app.sex = uidropdown(app.SubjectformPanel);
            app.sex.ValueChangedFcn = createCallbackFcn(app, @sexValueChanged, true);
            app.mark_typeLabel = uilabel(app.SubjectformPanel);
            app.mark_typeLabel.Text = 'mark_type*';
            app.mark_type = uidropdown(app.SubjectformPanel);
            app.mark_type.ValueChangedFcn = createCallbackFcn(app, @mark_typeValueChanged, true);
            app.markEditFieldLabel = uilabel(app.SubjectformPanel);
            app.markEditFieldLabel.Text = 'mark';
            app.mark = uieditfield(app.SubjectformPanel, 'text');
            app.mark.ValueChangedFcn = createCallbackFcn(app, @markValueChanged, true);
            app.strain_nameLabel = uilabel(app.SubjectformPanel);
            app.strain_nameLabel.Text = 'strain_name*';
            app.strain_name = uidropdown(app.SubjectformPanel);
            app.strain_name.ValueChangedFcn = createCallbackFcn(app, @strain_nameValueChanged, true);
            app.source_nameLabel = uilabel(app.SubjectformPanel);
            app.source_nameLabel.Text = 'source_name*';
            app.source_name = uidropdown(app.SubjectformPanel);
            app.source_name.ValueChangedFcn = createCallbackFcn(app, @source_nameValueChanged, true);
            app.breeding_pairEditFieldLabel = uilabel(app.SubjectformPanel);
            app.breeding_pairEditFieldLabel.Text = 'breeding_pair';
            app.breeding_pair = uieditfield(app.SubjectformPanel, 'text');
            app.breeding_pair.ValueChangedFcn = createCallbackFcn(app, @breeding_pairValueChanged, true);
            app.nicknameLabel = uilabel(app.SubjectformPanel);
            app.nicknameLabel.Text = 'nickname*';
            app.nickname = uieditfield(app.SubjectformPanel, 'text');
            app.nickname.ValueChangedFcn = createCallbackFcn(app, @nicknameValueChanged, true);
            app.subject_notesEditFieldLabel = uilabel(app.SubjectformPanel);
            app.subject_notesEditFieldLabel.Text = 'subject_notes';
            app.subject_notes = uieditfield(app.SubjectformPanel, 'text');
            app.subject_notes.ValueChangedFcn = createCallbackFcn(app, @subject_notesValueChanged, true);
            app.FlexiblequeryEditFieldLabel = uilabel(app.SubjectTab);
            app.FlexiblequeryEditFieldLabel.Text = 'Flexible query ';
            app.FlexiblequeryEditField = uieditfield(app.SubjectTab, 'text');
            app.nicknameDropDownLabel = uilabel(app.SubjectTab);
            app.nicknameDropDownLabel.Text = 'nickname';
            app.nicknameDropDown = uidropdown(app.SubjectTab);
            app.bornbeforeDatePickerLabel = uilabel(app.SubjectTab);
            app.bornbeforeDatePickerLabel.Text = 'born before';
            app.bornbeforeDatePicker = uidatepicker(app.SubjectTab);
            app.bornafterDatePickerLabel = uilabel(app.SubjectTab);
            app.bornafterDatePickerLabel.Text = 'born after';
            app.bornafterDatePicker = uidatepicker(app.SubjectTab);
            app.strainDropDownLabel = uilabel(app.SubjectTab);
            app.strainDropDownLabel.Text = 'strain';
            app.strainDropDown = uidropdown(app.SubjectTab);
            app.MessageTextAreaLabel = uilabel(app.SubjectTab);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.SubjectTab);
            app.SubmitfilterButton = uibutton(app.SubjectTab, 'push');
            app.SubmitfilterButton.Text = 'Submit filter';
            app.ResetfilterButton = uibutton(app.SubjectTab, 'push');
            app.ResetfilterButton.Text = 'Reset filter';
            app.DeletethemouserecordButton = uibutton(app.SubjectTab, 'push');
            app.DeletethemouserecordButton.Text = 'Delete the mouse record';
            app.CleartheformButton = uibutton(app.SubjectTab, 'push');
            app.CleartheformButton.Text = 'Clear the form';
            app.UpdatethemouserecordButton = uibutton(app.SubjectTab, 'push');
            app.UpdatethemouserecordButton.Text = 'Update the mouse record';
            app.SurgeryTab = uitab(app.TabGroup);
            app.SurgeryTab.Title = 'Surgery';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HillmanlabMouseGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end